classdef InterfaceForImageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label_38                 matlab.ui.control.Label
        Label_37                 matlab.ui.control.Label
        Label_36                 matlab.ui.control.Label
        Label_35                 matlab.ui.control.Label
        Label_34                 matlab.ui.control.Label
        Label_33                 matlab.ui.control.Label
        Label_32                 matlab.ui.control.Label
        Label_31                 matlab.ui.control.Label
        Label_30                 matlab.ui.control.Label
        Label_29                 matlab.ui.control.Label
        Label_28                 matlab.ui.control.Label
        Label_27                 matlab.ui.control.Label
        Label_26                 matlab.ui.control.Label
        Label_25                 matlab.ui.control.Label
        Label_24                 matlab.ui.control.Label
        Label_23                 matlab.ui.control.Label
        Label_21                 matlab.ui.control.Label
        Label_19                 matlab.ui.control.Label
        Label_17                 matlab.ui.control.Label
        Label_16                 matlab.ui.control.Label
        Label_15                 matlab.ui.control.Label
        Label_14                 matlab.ui.control.Label
        PROCESSLEVELSlider       matlab.ui.control.Slider
        PROCESSLEVELSliderLabel  matlab.ui.control.Label
        IMAGEPATHEditFieldLabel  matlab.ui.control.Label
        Label_13                 matlab.ui.control.Label
        Label_11                 matlab.ui.control.Label
        Label_10                 matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        PLANARDRAWINGROBOTLabel  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        RobotPlanarButton        matlab.ui.control.Button
        TURNOFFCAMERAButton      matlab.ui.control.Button
        TURNONCAMERAButton       matlab.ui.control.Button
        PROCESSButton            matlab.ui.control.Button
        IMAGEPATHEditField       matlab.ui.control.EditField
        TAKEPICTUREButton        matlab.ui.control.Button
        LOADIMAGEButton          matlab.ui.control.Button
        Label_7                  matlab.ui.control.Label
        Label_12                 matlab.ui.control.Label
        Label_18                 matlab.ui.control.Label
        ProcessedImageAxes       matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ImagePath % Variable para guardar la ruta de la imagen
        cam;
        camTimer;
        silderValue = 0; % Description
    end
    
    methods (Access = public)
        
        function processImage(img, app)
            th = app.PROCESSLEVELSlider.Value;
            % Ajustar umbrales para Canny
            lowThreshold = th;
            highThreshold = min(th + 0.1, 1);  % Asegura que no se pase de 1


          % Convertir a escala de grises
    gray = rgb2gray(img);
    
    % Aplicar filtro Gaussiano
    blurred = imgaussfilt(gray, 0.3);
    
    % Detección de bordes combinada (Canny + Sobel)
    edges = edge(blurred, 'Canny', [lowThreshold, highThreshold]);
    sobelEdges = edge(blurred, 'Sobel');
    combinedEdges = edges | sobelEdges;
    
    % Filtrar ruido
    filteredEdges = bwareaopen(combinedEdges, 30);
    
    % Mostrar los bordes procesados en la app
    imshow(filteredEdges, 'Parent', app.ProcessedImageAxes);
    
    % Extraer contornos y guardar waypoints CON ESTRUCTURA
    [B, ~] = bwboundaries(filteredEdges, 'noholes');
    
    % OPCIÓN 1: Guardar cada contorno por separado en una estructura
    waypoints_structure = struct('contours', cell(1, length(B)));
    for k = 1:length(B)
        boundary = B{k};
        waypoints_structure(k).contours = boundary;
    end
    
    % OPCIÓN 2: Guardar todos los waypoints juntos pero con información de separación
    waypoints = [];
    contour_breaks = []; % Índices donde terminan los contornos
    
    for k = 1:length(B)
        boundary = B{k};
        waypoints = [waypoints; boundary];
        contour_breaks = [contour_breaks; size(waypoints,1)];
    end
    
    % Guardar ambas estructuras de datos en el workspace
    assignin('base', 'waypoints_structure', waypoints_structure);
    assignin('base', 'waypoints', waypoints);
    assignin('base', 'contour_breaks', contour_breaks);
   
end
        
function rgb = cmyk2rgb(cmyk)
    % CMYK a RGB: formula de conversión
    c = cmyk(:,:,1);
    m = cmyk(:,:,2);
    y = cmyk(:,:,3);
    k = cmyk(:,:,4);
    
    r = (1 - c) .* (1 - k);
    g = (1 - m) .* (1 - k);
    b = (1 - y) .* (1 - k);
    
    % Empaquetar en una imagen RGB
    rgb = cat(3, r, g, b);
end


    end
    
    methods (Access = private)
        
        function updateLiveFeed(app)
    if ~isempty(app.cam)
        img = snapshot(app.cam); % Captura la imagen en vivo
        imshow(img, 'Parent', app.UIAxes); % Muestra en la app
    end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADIMAGEButton
        function LOADIMAGEButtonPushed(app, event)
             assignin('base', 'Value', 'A');
% Abrir cuadro de diálogo para seleccionar imagen
[file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tiff', 'Imagenes (*.png,*.jpg,*.jpeg,*.bmp,*.tiff)'}, 'Seleccionar Imagen');

% Verificar si el usuario seleccionó un archivo
if isequal(file, 0)
    disp('No se seleccionó ningún archivo.');
else
    % Guardar la ruta completa de la imagen en la app
    app.ImagePath = fullfile(path, file);
    
    % Mostrar la ruta en un Label o Edit Field
    app.IMAGEPATHEditField.Value = app.ImagePath;
    
    % Cargar la imagen
    img = imread(app.ImagePath);
    
    % Verificar si la imagen es CMYK y convertir a RGB si es necesario
    if size(img, 3) == 4  % CMYK (tiene 4 canales)
        % Convertir CMYK a RGB
        img = cmyk2rgb(img);
    end
    
    % Mostrar la imagen en un `UIAxes` (opcional)
    imshow(img, 'Parent', app.UIAxes);
    end
        end

        % Button pushed function: PROCESSButton
        function PROCESSButtonPushed(app, event)
            if isempty(app.ImagePath)
        uialert(app.UIFigure, 'Selecciona una imagen antes de ejecutar el script.', 'Error');
        return;
            else
    % Cargar la imagen
    img = imread(app.ImagePath);
    
    % Mostrar la imagen en la app
    imshow(img, 'Parent', app.ProcessedImageAxes);

    % Guardar la imagen en el workspace
    assignin('base', 'capturedImage', img);
    assignin('base', 'Value', 'B'); % Indicar que se cargó una imagen

    % Aplicar el mismo procesamiento
    processImage(img, app);
    end
    
    
        end

        % Button pushed function: TAKEPICTUREButton
        function TAKEPICTUREButtonPushed(app, event)
            if ~isempty(app.cam)
        % Capturar la imagen
        img = snapshot(app.cam);
        
        % Detener el timer para evitar interferencias
        stop(app.camTimer);
        delete(app.camTimer);
        app.camTimer = [];
        
        % Mostrar la imagen capturada
        imshow(img, 'Parent', app.ProcessedImageAxes);
        
        % Guardar la imagen en el workspace
        assignin('base', 'capturedImage', img);
        assignin('base', 'Value', 'B');
        
        % Llamar a la función de procesamiento
        processImage(img, app);
    end
        end

        % Button pushed function: TURNONCAMERAButton
        function TURNONCAMERAButtonPushed(app, event)
            % Verifica si la cámara ya está activa
    if isempty(app.cam)
        app.cam = webcam; % Inicializa la cámara
    end

    % Verificar si el timer ya está activo para evitar duplicados
    if isempty(app.camTimer) || ~isvalid(app.camTimer)
        % Crear un timer para actualizar la imagen en vivo
        app.camTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                             'TimerFcn', @(~,~) updateLiveFeed(app));

        % Iniciar el timer para capturar imágenes en vivo
        start(app.camTimer);
    end
        end

        % Button pushed function: TURNOFFCAMERAButton
        function TURNOFFCAMERAButtonPushed(app, event)
            if isa(app.camTimer, 'timer') && isvalid(app.camTimer)
        stop(app.camTimer);
        delete(app.camTimer);
        app.camTimer = []; % Limpiar la variable
    end
    
    % Liberar la cámara
    if ~isempty(app.cam)
        clear app.cam;
        app.cam = [];
    end
    
    % Limpiar la imagen en UIAxes
    cla(app.UIAxes);
    text(app.UIAxes, 0.5, 0.5, 'Cámara Apagada', 'FontSize', 14, 'HorizontalAlignment', 'center');
        end

        % Button pushed function: RobotPlanarButton
        function RobotPlanarButtonPushed(app, event)
    %% Robot Planar - Dibujo con detección de saltos entre contornos

% 1) Recuperar contornos (waypoints) desde el workspace
if evalin('base', "exist('waypoints', 'var')") == 0
    uialert(app.UIFigure, ...
        'No se encontró la variable waypoints en el workspace.', ...
        'Error');
    return;
end

rawWaypoints = evalin('base', 'waypoints');
if isempty(rawWaypoints)
    uialert(app.UIFigure, 'La variable waypoints está vacía.', 'Error');
    return;
end

% 2) Configurar robot
l1 = 2; l2 = 2; l3 = 1; % longitudes de los eslabones
L(1) = Link([0 0 l1 0 0]);
L(2) = Link([0 0 l2 0 0]);
L(3) = Link([0 0 l3 0 0]);
planar = SerialLink(L, 'name', 'Planar');

% 3) Reescalar los puntos para un área de trabajo 4x4
allRows = rawWaypoints(:,1); % y
allCols = rawWaypoints(:,2); % x
minRow = min(allRows); maxRow = max(allRows);
minCol = min(allCols); maxCol = max(allCols);
widthPx = maxCol - minCol;
heightPx = maxRow - minRow;

workspace_size = 4;
centerXY = [2, 2];
scaleFactor = workspace_size / max(widthPx, heightPx);
xOffset = centerXY(1) - (widthPx * scaleFactor) / 2;
yOffset = centerXY(2) - (heightPx * scaleFactor) / 2;

% 4) Separar segmentos con base en distancia
distanceThreshold = 10; % en pixeles
segments = {};
currentSegment = rawWaypoints(1,:);
segmentIndex = 1;

for i = 2:size(rawWaypoints, 1)
    dist = norm(rawWaypoints(i,:) - rawWaypoints(i-1,:));
    if dist > distanceThreshold
        segments{segmentIndex} = currentSegment;
        segmentIndex = segmentIndex + 1;
        currentSegment = rawWaypoints(i,:);
    else
        currentSegment = [currentSegment; rawWaypoints(i,:)];
    end
end
segments{segmentIndex} = currentSegment; % último segmento

% 5) Calcular trayectoria e IK
Qtotal = [];
PenStates = [];
qPrev = [0 0 0];
subFactor = 10;

for s = 1:length(segments)
    seg = segments{s};
    stepInds = 1:subFactor:size(seg,1);
    if stepInds(end) ~= size(seg,1)
        stepInds = [stepInds, size(seg,1)];
    end

    % Si no es el primer segmento, interpolamos desde qPrev
    if s > 1
        % Primer punto del nuevo segmento
        r = seg(stepInds(1),1);
        c = seg(stepInds(1),2);
        x = (c - minCol)*scaleFactor + xOffset;
        y = (r - minRow)*scaleFactor + yOffset;

        % Calcular qStart (postura inicial del nuevo segmento)
        phi = atan2(y, x);
        x2 = x - l3*cos(phi);
        y2 = y - l3*sin(phi);
        r2 = x2^2 + y2^2;
        cosT2 = (r2 - l1^2 - l2^2)/(2*l1*l2);
        cosT2 = max(min(cosT2,1),-1);
        theta2 = acos(cosT2);
        sinT2 = sin(theta2);
        theta1 = atan2(y2, x2) - atan2(l2*sinT2, l1 + l2*cosT2);
        theta3 = phi - theta1 - theta2;
        qStart = [theta1 theta2 theta3];

        % Interpolación suave entre qPrev y qStart
        stepsTrans = 30;
        qInterp = jtraj(qPrev, qStart, stepsTrans);
        Qtotal = [Qtotal; qInterp];
        PenStates = [PenStates; zeros(stepsTrans,1)]; % plumón arriba
    end

    % Recorrido del segmento actual
    for idx = stepInds
        r = seg(idx,1);
        c = seg(idx,2);
        x = (c - minCol)*scaleFactor + xOffset;
        y = (r - minRow)*scaleFactor + yOffset;

        % Cinemática inversa
        phi = atan2(y, x);
        x2 = x - l3*cos(phi);
        y2 = y - l3*sin(phi);
        r2 = x2^2 + y2^2;
        cosT2 = (r2 - l1^2 - l2^2)/(2*l1*l2);
        cosT2 = max(min(cosT2,1),-1);
        theta2 = acos(cosT2);
        sinT2 = sin(theta2);
        theta1 = atan2(y2, x2) - atan2(l2*sinT2, l1 + l2*cosT2);
        theta3 = phi - theta1 - theta2;

        q = [theta1, theta2, theta3];
        Qtotal = [Qtotal; q];

        if idx == stepInds(1)
            PenStates = [PenStates; 0]; % plumón arriba
        else
            PenStates = [PenStates; 1]; % plumón abajo
        end

        qPrev = q;
    end
end


% 6) Animación personalizada (dibujo solo cuando el plumón está abajo)
figure('Name','Robot Planar');
axis equal
view(3)
planar.plot(Qtotal(1,:), 'workspace', [-1 6 -1 6 -1 6]);
hold on
drawnow

for i = 2:size(Qtotal,1)
    planar.plot(Qtotal(i,:), 'noraise');
    if PenStates(i) == 1
        T1 = planar.fkine(Qtotal(i-1,:));
        T2 = planar.fkine(Qtotal(i,:));
        p1 = T1.t; p2 = T2.t;
        plot3([p1(1) p2(1)], [p1(2) p2(2)], [p1(3) p2(3)], 'r', 'LineWidth', 0.5);
    end
    pause(0.01);
end
% 7) Regresar a la posición inicial (plumón arriba)
qFinal = Qtotal(end,:);
qInicial = Qtotal(1,:);
stepsBack = 50;
qTrayBack = jtraj(qFinal, qInicial, stepsBack);

for i = 1:stepsBack
    planar.plot(qTrayBack(i,:), 'noraise');
    % No se dibuja línea porque el plumón está "arriba"
    pause(0.01);
end


title('Robot Planar dibujando contornos sin unir saltos grandes');


        end

        % Value changed function: PROCESSLEVELSlider
        function PROCESSLEVELSliderValueChanged(app, event)
            app.silderValue = app.PROCESSLEVELSlider.Value;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SELECTED/REAL TIME IMAGE')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.ProcessedImageAxes = uiaxes(app.UIFigure);
            title(app.ProcessedImageAxes, 'Processed Image')
            app.ProcessedImageAxes.XTick = [];
            app.ProcessedImageAxes.YTick = [];
            app.ProcessedImageAxes.YTickLabel = '';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.LOADIMAGEButton = uibutton(app.UIFigure, 'push');
            app.LOADIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADIMAGEButtonPushed, true);
            app.LOADIMAGEButton.Text = 'LOAD IMAGE';
            app.TAKEPICTUREButton = uibutton(app.UIFigure, 'push');
            app.TAKEPICTUREButton.ButtonPushedFcn = createCallbackFcn(app, @TAKEPICTUREButtonPushed, true);
            app.TAKEPICTUREButton.Text = 'TAKE PICTURE';
            app.IMAGEPATHEditField = uieditfield(app.UIFigure, 'text');
            app.PROCESSButton = uibutton(app.UIFigure, 'push');
            app.PROCESSButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSButtonPushed, true);
            app.PROCESSButton.Text = 'PROCESS';
            app.TURNONCAMERAButton = uibutton(app.UIFigure, 'push');
            app.TURNONCAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @TURNONCAMERAButtonPushed, true);
            app.TURNONCAMERAButton.Text = 'TURN ON CAMERA';
            app.TURNOFFCAMERAButton = uibutton(app.UIFigure, 'push');
            app.TURNOFFCAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @TURNOFFCAMERAButtonPushed, true);
            app.TURNOFFCAMERAButton.Text = 'TURN OFF CAMERA';
            app.RobotPlanarButton = uibutton(app.UIFigure, 'push');
            app.RobotPlanarButton.ButtonPushedFcn = createCallbackFcn(app, @RobotPlanarButtonPushed, true);
            app.RobotPlanarButton.Text = 'Robot Planar';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.PLANARDRAWINGROBOTLabel = uilabel(app.UIFigure);
            app.PLANARDRAWINGROBOTLabel.Text = 'PLANAR DRAWING ROBOT';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.IMAGEPATHEditFieldLabel = uilabel(app.UIFigure);
            app.IMAGEPATHEditFieldLabel.Text = 'IMAGE PATH';
            app.PROCESSLEVELSliderLabel = uilabel(app.UIFigure);
            app.PROCESSLEVELSliderLabel.Text = 'PROCESS LEVEL';
            app.PROCESSLEVELSlider = uislider(app.UIFigure);
            app.PROCESSLEVELSlider.Limits = [0.01 0.2];
            app.PROCESSLEVELSlider.ValueChangedFcn = createCallbackFcn(app, @PROCESSLEVELSliderValueChanged, true);
            app.PROCESSLEVELSlider.Value = 0.05;
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '';
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '';
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '';
            app.Label_25 = uilabel(app.UIFigure);
            app.Label_25.Text = '';
            app.Label_26 = uilabel(app.UIFigure);
            app.Label_26.Text = '';
            app.Label_27 = uilabel(app.UIFigure);
            app.Label_27.Text = '';
            app.Label_28 = uilabel(app.UIFigure);
            app.Label_28.Text = '';
            app.Label_29 = uilabel(app.UIFigure);
            app.Label_29.Text = '';
            app.Label_30 = uilabel(app.UIFigure);
            app.Label_30.Text = '';
            app.Label_31 = uilabel(app.UIFigure);
            app.Label_31.Text = '';
            app.Label_32 = uilabel(app.UIFigure);
            app.Label_32.Text = '';
            app.Label_33 = uilabel(app.UIFigure);
            app.Label_33.Text = '';
            app.Label_34 = uilabel(app.UIFigure);
            app.Label_34.Text = '';
            app.Label_35 = uilabel(app.UIFigure);
            app.Label_35.Text = '';
            app.Label_36 = uilabel(app.UIFigure);
            app.Label_36.Text = '';
            app.Label_37 = uilabel(app.UIFigure);
            app.Label_37.Text = '';
            app.Label_38 = uilabel(app.UIFigure);
            app.Label_38.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceForImageProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end